classdef BaslerCamera2018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ExposureTab                     matlab.ui.container.Tab
        ExposureAuto                    matlab.ui.control.CheckBox
        ExposureSpinner                 matlab.ui.control.Spinner
        ExposureSlider                  matlab.ui.control.Slider
        ExposuretimeusLabel             matlab.ui.control.Label
        GainTab                         matlab.ui.container.Tab
        GainAuto                        matlab.ui.control.CheckBox
        GainSpinner                     matlab.ui.control.Spinner
        GainSlider                      matlab.ui.control.Slider
        GaindBLabel                     matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        RecordingTab                    matlab.ui.container.Tab
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        PlaybackFramesPerSecondSpinnerLabel  matlab.ui.control.Label
        PlaybackFramesPerSecondSpinner  matlab.ui.control.Spinner
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PlayingTab                      matlab.ui.container.Tab
        FilenameEditField_2Label        matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        FrameSpinnerLabel               matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        FrameLoadButton                 matlab.ui.control.Button
        PlayvideoButton                 matlab.ui.control.Button
        CameraNameLablel                matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        PreviewTab                      matlab.ui.container.Tab
        OpenButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SnapshotButton                  matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        UIAxes1Tab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2Tab                      matlab.ui.container.Tab
        Tab3                            matlab.ui.container.Tab
    end


    properties (Access = private)
        Camera % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dllPath = fullfile(pwd, "BaslerCameraWrapper.dll");
            NET.addAssembly(dllPath);
            app.Camera = BaslerCameraWrapper.BaslerCamera();
            app.CameraNameLablel.Text = string(app.Camera.Name);
            app.Camera.Open();
            
            exposureAuto = app.Camera.ExposureAuto;
            app.ExposureAuto.Value = exposureAuto;
            app.ExposureSpinner.Enable = ~exposureAuto;
            app.ExposureSlider.Enable = ~exposureAuto;
            
            exposureMin = app.Camera.ExposureTimeMin;
            exposureMax = app.Camera.ExposureTimeMax;
            app.ExposureSlider.Limits = [exposureMin, exposureMax];
            app.ExposureSpinner.Limits = [exposureMin, exposureMax];
            
            exposureTime = app.Camera.ExposureTime;
            app.ExposureSlider.Value = exposureTime;
            app.ExposureSpinner.Value = exposureTime;
            
            gainAuto = app.Camera.GainAuto;
            app.GainAuto.Value = gainAuto;
            app.GainSlider.Enable = ~gainAuto;
            app.GainSpinner.Enable = ~gainAuto;
            
            gainMin = app.Camera.GainMin;
            gainMax = app.Camera.GainMax; 
            app.GainSlider.Limits = [gainMin, gainMax];
            app.GainSpinner.Limits = [gainMin, gainMax];
            
            gain = app.Camera.Gain;
            app.GainSlider.Value = gain;
            app.GainSpinner.Value = gain;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Camera.Close();
            delete(app) 
        end

        % Value changed function: ExposureAuto
        function ExposureAutoValueChanged(app, event)
            value = app.ExposureAuto.Value;
            app.Camera.ExposureAuto = value;
            app.ExposureSpinner.Enable = ~value;
            app.ExposureSlider.Enable = ~value;
        end

        % Value changed function: ExposureSpinner
        function ExposureSpinnerValueChanged(app, event)
            value = app.ExposureSpinner.Value;
            app.Camera.ExposureTime = value;
            app.ExposureSlider.Value = value;
        end

        % Value changed function: ExposureSlider
        function ExposureSliderValueChanged(app, event)
            value = app.ExposureSlider.Value;
            app.Camera.ExposureTime = value;
            app.ExposureSpinner.Value = value;
        end

        % Value changed function: GainAuto
        function GainAutoValueChanged(app, event)
            value = app.GainAuto.Value;
            app.Camera.GainAuto = value;
            app.GainSpinner.Enable = ~value;
            app.GainSlider.Enable = ~value;
        end

        % Value changed function: GainSpinner
        function GainSpinnerValueChanged(app, event)
            value = app.GainSpinner.Value;
            app.Camera.Gain = value;
            app.GainSlider.Value = value;
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            value = app.GainSlider.Value;
            app.Camera.Gain = value;
            app.GainSpinner.Value = value;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.Camera.OpenPreview();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Camera.ClosePreview();
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            netArray = app.Camera.GetFrame();
            image =  uint8(netArray);
            imshow(image,'Parent',app.UIAxes);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            fileName = app.FilenameEditField.Value;
            fps = app.PlaybackFramesPerSecondSpinner.Value;
            app.Camera.StartRecord(fileName, fps);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Camera.StopRecord();
        end

        % Button pushed function: FrameLoadButton
        function FrameLoadButtonPushed(app, event)
            frameNumber = app.FrameSpinner.Value;
            fileName = app.FilenameEditField_2.Value;
            v = VideoReader(fileName);
            frame = read(v, frameNumber);
            imshow(frame,'Parent',app.UIAxes);
        end

        % Button pushed function: PlayvideoButton
        function PlayvideoButtonPushed(app, event)
            fileName = app.FilenameEditField_2.Value;
            v = VideoReader(fileName);
            currAxes = app.UIAxes;
            while hasFrame(v)
                vidFrame = readFrame(v);
                image(vidFrame, 'Parent', currAxes);
                currAxes.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExposureTab = uitab(app.TabGroup);
            app.ExposureTab.Title = 'Exposure';
            app.ExposureAuto = uicheckbox(app.ExposureTab);
            app.ExposureAuto.ValueChangedFcn = createCallbackFcn(app, @ExposureAutoValueChanged, true);
            app.ExposureAuto.Text = 'Auto';
            app.ExposureSpinner = uispinner(app.ExposureTab);
            app.ExposureSpinner.ValueDisplayFormat = '%.0f';
            app.ExposureSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposureSpinnerValueChanged, true);
            app.ExposureSlider = uislider(app.ExposureTab);
            app.ExposureSlider.MajorTicks = [];
            app.ExposureSlider.MajorTickLabels = {};
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [];
            app.ExposureSlider.Value = 10;
            app.ExposuretimeusLabel = uilabel(app.ExposureTab);
            app.ExposuretimeusLabel.Text = 'Exposure time (us) ';
            app.GainTab = uitab(app.TabGroup);
            app.GainTab.Title = 'Gain';
            app.GainAuto = uicheckbox(app.GainTab);
            app.GainAuto.ValueChangedFcn = createCallbackFcn(app, @GainAutoValueChanged, true);
            app.GainAuto.Text = 'Auto';
            app.GainSpinner = uispinner(app.GainTab);
            app.GainSpinner.ValueChangedFcn = createCallbackFcn(app, @GainSpinnerValueChanged, true);
            app.GainSlider = uislider(app.GainTab);
            app.GainSlider.MajorTicks = [];
            app.GainSlider.MajorTickLabels = {};
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.MinorTicks = [];
            app.GainSlider.Value = 10;
            app.GaindBLabel = uilabel(app.GainTab);
            app.GaindBLabel.Text = 'Gain (dB)';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RecordingTab = uitab(app.TabGroup2);
            app.RecordingTab.Title = 'Recording';
            app.FilenameEditFieldLabel = uilabel(app.RecordingTab);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.RecordingTab, 'text');
            app.FilenameEditField.Value = 'myFile.mp4';
            app.PlaybackFramesPerSecondSpinnerLabel = uilabel(app.RecordingTab);
            app.PlaybackFramesPerSecondSpinnerLabel.Text = 'Playback Frames Per Second';
            app.PlaybackFramesPerSecondSpinner = uispinner(app.RecordingTab);
            app.PlaybackFramesPerSecondSpinner.Limits = [1 1000];
            app.PlaybackFramesPerSecondSpinner.ValueDisplayFormat = '%.0f';
            app.PlaybackFramesPerSecondSpinner.Value = 25;
            app.StartButton = uibutton(app.RecordingTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecordingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayingTab = uitab(app.TabGroup2);
            app.PlayingTab.Title = 'Playing';
            app.FilenameEditField_2Label = uilabel(app.PlayingTab);
            app.FilenameEditField_2Label.Text = 'File name:';
            app.FilenameEditField_2 = uieditfield(app.PlayingTab, 'text');
            app.FilenameEditField_2.Value = 'myFile.mp4';
            app.FrameSpinnerLabel = uilabel(app.PlayingTab);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.FrameSpinner = uispinner(app.PlayingTab);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueDisplayFormat = '%.0f';
            app.FrameSpinner.Value = 1;
            app.FrameLoadButton = uibutton(app.PlayingTab, 'push');
            app.FrameLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FrameLoadButtonPushed, true);
            app.FrameLoadButton.Text = 'Load';
            app.PlayvideoButton = uibutton(app.PlayingTab, 'push');
            app.PlayvideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayvideoButtonPushed, true);
            app.PlayvideoButton.Text = 'Play video';
            app.CameraNameLablel = uilabel(app.UIFigure);
            app.CameraNameLablel.Text = '';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.PreviewTab = uitab(app.TabGroup3);
            app.PreviewTab.Title = 'Preview';
            app.OpenButton = uibutton(app.PreviewTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.PreviewTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SnapshotButton = uibutton(app.PreviewTab, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.UIAxes1Tab = uitab(app.TabGroup4);
            app.UIAxes1Tab.Title = 'UIAxes1';
            app.UIAxes = uiaxes(app.UIAxes1Tab);
            app.UIAxes2Tab = uitab(app.TabGroup4);
            app.UIAxes2Tab.Title = 'UIAxes2';
            app.Tab3 = uitab(app.TabGroup4);
            app.Tab3.Title = 'Tab3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BaslerCamera2018

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end