classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ExposureTimesPanel              matlab.ui.container.Panel
        AutoExposure                    matlab.ui.control.CheckBox
        ExposureSpinner                 matlab.ui.control.Spinner
        ExposureSlider                  matlab.ui.control.Slider
        GaindbPanel                     matlab.ui.container.Panel
        GainSlider                      matlab.ui.control.Slider
        GainSpinner                     matlab.ui.control.Spinner
        AutoGain                        matlab.ui.control.CheckBox
        CameraNameLabel                 matlab.ui.control.Label
        OpenpreviewButton               matlab.ui.control.Button
        ClosepreviewButton              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SnapshotButton                  matlab.ui.control.Button
        RecordingvideoPanel             matlab.ui.container.Panel
        StartVideoButton                matlab.ui.control.Button
        StopVideoButton                 matlab.ui.control.Button
        FileNameLabel                   matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        RecordanimageeverySpinnerLabel  matlab.ui.control.Label
        RecordanimageeverySpinner       matlab.ui.control.Spinner
        msLabel                         matlab.ui.control.Label
        PlaybackFramesPerSecondSpinnerLabel  matlab.ui.control.Label
        PlaybackFramesPerSecondSpinner  matlab.ui.control.Spinner
        PlayingvideoPanel               matlab.ui.container.Panel
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        FrameSpinnerLabel               matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        LoadButton                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Camera % Description
    end
    
    methods (Access = private)
        
        function grabResultListener(source, arg)
            disp('hellp here');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dllPath = strrep(pwd, "MatlabApp", "BaslerCameraWrapper\BaslerCameraWrapper\bin\Debug\BaslerCameraWrapper.dll");
            NET.addAssembly(dllPath);
            app.Camera = BaslerCameraWrapper.BaslerCamera();
            app.CameraNameLabel.Text = string(app.Camera.Name);
            app.Camera.Open();
            
            exposureAuto = app.Camera.ExposureAuto;
            app.AutoExposure.Value = exposureAuto;
            app.ExposureSpinner.Enable = ~exposureAuto;
            app.ExposureSlider.Enable = ~exposureAuto;
            
            exposureMin = app.Camera.ExposureTimeMin;
            exposureMax = app.Camera.ExposureTimeMax;
            app.ExposureSlider.Limits = [exposureMin, exposureMax];
            app.ExposureSpinner.Limits = [exposureMin, exposureMax];
            
            exposureTime = app.Camera.ExposureTime;
            app.ExposureSlider.Value = exposureTime;
            app.ExposureSpinner.Value = exposureTime;
            
            gainAuto = app.Camera.GainAuto;
            app.AutoGain.Value = gainAuto;
            app.GainSlider.Enable = ~gainAuto;
            app.GainSpinner.Enable = ~gainAuto;
            
            gainMin = app.Camera.GainMin;
            gainMax = app.Camera.GainMax; 
            app.GainSlider.Limits = [gainMin, gainMax];
            app.GainSpinner.Limits = [gainMin, gainMax];
            
            gain = app.Camera.Gain;
            app.GainSlider.Value = gain;
            app.GainSpinner.Value = gain;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Camera.Close();
            delete(app)
        end

        % Value changed function: ExposureSlider
        function ExposureSliderValueChanged(app, event)
            value = app.ExposureSlider.Value;
            app.Camera.ExposureTime = value;
            app.ExposureSpinner.Value = value;
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            netArray = app.Camera.GetFrame();
            mArr =  uint8(netArray);
            imshow(mArr,'Parent',app.UIAxes);
        end

        % Value changed function: AutoExposure
        function AutoExposureValueChanged(app, event)
            value = app.AutoExposure.Value;
            app.Camera.ExposureAuto = value;
            app.ExposureSpinner.Enable = ~value;
            app.ExposureSlider.Enable = ~value;
        end

        % Value changed function: AutoGain
        function AutoGainValueChanged(app, event)
            value = app.AutoGain.Value;
            app.Camera.GainAuto = value;
            app.GainSpinner.Enable = ~value;
            app.GainSlider.Enable = ~value;
        end

        % Value changed function: ExposureSpinner
        function ExposureSpinnerValueChanged(app, event)
            value = app.ExposureSpinner.Value;
            app.Camera.ExposureTime = value;
            app.ExposureSlider.Value = value;
        end

        % Value changed function: GainSpinner
        function GainSpinnerValueChanged(app, event)
            value = app.GainSpinner.Value;
            app.Camera.Gain = value;
            app.GainSlider.Value = value;
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            value = app.GainSlider.Value;
            app.Camera.Gain = value;
            app.GainSpinner.Value = value;
        end

        % Button pushed function: OpenpreviewButton
        function OpenpreviewButtonPushed(app, event)
            app.Camera.OpenPreview();
        end

        % Button pushed function: ClosepreviewButton
        function ClosepreviewButtonPushed(app, event)
            app.Camera.ClosePreview();
        end

        % Button pushed function: StopVideoButton
        function StopVideoButtonPushed(app, event)
            app.Camera.StopRecord();
        end

        % Button pushed function: StartVideoButton
        function StartVideoButtonPushed(app, event)
            fileName = app.FileNameEditField.Value;
            fps = app.PlaybackFramesPerSecondSpinner.Value;
            app.Camera.StartRecord(fileName, fps);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x', 276};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExposureTimesPanel = uipanel(app.LeftPanel);
            app.ExposureTimesPanel.Title = 'Exposure Time (μs)';
            app.AutoExposure = uicheckbox(app.ExposureTimesPanel);
            app.AutoExposure.ValueChangedFcn = createCallbackFcn(app, @AutoExposureValueChanged, true);
            app.AutoExposure.Text = 'Auto';
            app.ExposureSpinner = uispinner(app.ExposureTimesPanel);
            app.ExposureSpinner.Step = 10;
            app.ExposureSpinner.Limits = [10 1000000];
            app.ExposureSpinner.ValueDisplayFormat = '%.0f';
            app.ExposureSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposureSpinnerValueChanged, true);
            app.ExposureSpinner.Value = 10000;
            app.ExposureSlider = uislider(app.ExposureTimesPanel);
            app.ExposureSlider.Limits = [10 1000000];
            app.ExposureSlider.MajorTicks = [];
            app.ExposureSlider.MajorTickLabels = {''};
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [];
            app.ExposureSlider.Value = 10;
            app.GaindbPanel = uipanel(app.LeftPanel);
            app.GaindbPanel.Title = 'Gain (db)';
            app.GainSlider = uislider(app.GaindbPanel);
            app.GainSlider.Limits = [0 81.1];
            app.GainSlider.MajorTicks = [];
            app.GainSlider.MajorTickLabels = {'', ''};
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.MinorTicks = [];
            app.GainSpinner = uispinner(app.GaindbPanel);
            app.GainSpinner.ValueChangedFcn = createCallbackFcn(app, @GainSpinnerValueChanged, true);
            app.AutoGain = uicheckbox(app.GaindbPanel);
            app.AutoGain.ValueChangedFcn = createCallbackFcn(app, @AutoGainValueChanged, true);
            app.AutoGain.Text = 'Auto';
            app.CameraNameLabel = uilabel(app.LeftPanel);
            app.CameraNameLabel.Text = '';
            app.OpenpreviewButton = uibutton(app.LeftPanel, 'push');
            app.OpenpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @OpenpreviewButtonPushed, true);
            app.OpenpreviewButton.Text = 'Open preview';
            app.ClosepreviewButton = uibutton(app.LeftPanel, 'push');
            app.ClosepreviewButton.ButtonPushedFcn = createCallbackFcn(app, @ClosepreviewButtonPushed, true);
            app.ClosepreviewButton.Text = 'Close preview';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.SnapshotButton = uibutton(app.RightPanel, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.RecordingvideoPanel = uipanel(app.RightPanel);
            app.RecordingvideoPanel.Title = 'Recording video';
            app.StartVideoButton = uibutton(app.RecordingvideoPanel, 'push');
            app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoButtonPushed, true);
            app.StartVideoButton.Text = 'Start';
            app.StopVideoButton = uibutton(app.RecordingvideoPanel, 'push');
            app.StopVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StopVideoButtonPushed, true);
            app.StopVideoButton.Text = 'Stop';
            app.FileNameLabel = uilabel(app.RecordingvideoPanel);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.RecordingvideoPanel, 'text');
            app.RecordanimageeverySpinnerLabel = uilabel(app.RecordingvideoPanel);
            app.RecordanimageeverySpinnerLabel.Text = 'Record an image every';
            app.RecordanimageeverySpinner = uispinner(app.RecordingvideoPanel);
            app.RecordanimageeverySpinner.Value = 89;
            app.msLabel = uilabel(app.RecordingvideoPanel);
            app.msLabel.Text = 'ms';
            app.PlaybackFramesPerSecondSpinnerLabel = uilabel(app.RecordingvideoPanel);
            app.PlaybackFramesPerSecondSpinnerLabel.Text = 'Playback Frames Per Second';
            app.PlaybackFramesPerSecondSpinner = uispinner(app.RecordingvideoPanel);
            app.PlaybackFramesPerSecondSpinner.Limits = [1 500];
            app.PlaybackFramesPerSecondSpinner.ValueDisplayFormat = '%.0f';
            app.PlaybackFramesPerSecondSpinner.Value = 30;
            app.PlayingvideoPanel = uipanel(app.RightPanel);
            app.PlayingvideoPanel.Title = 'Playing video';
            app.FileNameEditField_2Label = uilabel(app.PlayingvideoPanel);
            app.FileNameEditField_2Label.Text = 'File Name:';
            app.FileNameEditField_2 = uieditfield(app.PlayingvideoPanel, 'text');
            app.FrameSpinnerLabel = uilabel(app.PlayingvideoPanel);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.FrameSpinner = uispinner(app.PlayingvideoPanel);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.Value = 1;
            app.LoadButton = uibutton(app.PlayingvideoPanel, 'push');
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end